classdef Mech < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ViewLabel                  matlab.ui.control.Label
        Theta3Button               matlab.ui.control.Button
        Theta2Button               matlab.ui.control.Button
        Theta1Button               matlab.ui.control.Button
        ViewButton_5               matlab.ui.control.Button
        ViewButton_4               matlab.ui.control.Button
        ViewButton_3               matlab.ui.control.Button
        ViewButton                 matlab.ui.control.Button
        ExitButton                 matlab.ui.control.Button
        AngularAccelerationLabel   matlab.ui.control.Label
        AngularVelocityLabel       matlab.ui.control.Label
        TextArea                   matlab.ui.control.TextArea
        StopButton                 matlab.ui.control.Button
        R3EditField_2              matlab.ui.control.NumericEditField
        R3EditField_2Label         matlab.ui.control.Label
        SearchButton               matlab.ui.control.Button
        AccelerationEditField_4    matlab.ui.control.NumericEditField
        AccelerationEditField_3    matlab.ui.control.NumericEditField
        AccelerationEditField_2    matlab.ui.control.NumericEditField
        VelocityEditField_4        matlab.ui.control.NumericEditField
        VelocityEditField_3        matlab.ui.control.NumericEditField
        VelocityEditField_2        matlab.ui.control.NumericEditField
        TimeEditField              matlab.ui.control.NumericEditField
        TimeEditFieldLabel         matlab.ui.control.Label
        OffsetSlider               matlab.ui.control.Slider
        OffsetSliderLabel          matlab.ui.control.Label
        R3EditField                matlab.ui.control.NumericEditField
        R3EditFieldLabel           matlab.ui.control.Label
        R1EditField                matlab.ui.control.NumericEditField
        R1EditFieldLabel           matlab.ui.control.Label
        R2EditField                matlab.ui.control.NumericEditField
        R2EditFieldLabel           matlab.ui.control.Label
        R0EditField                matlab.ui.control.NumericEditField
        R0EditFieldLabel           matlab.ui.control.Label
        PreviewButton              matlab.ui.control.Button
        WattLinkageSimulatorLabel  matlab.ui.control.Label
        AnimateButton              matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        R0 
        R1 
        R2 
        R3 
        A 
        B
        C
        D
        l
        time_
        theta_1
        theta_2
        theta_3
        W
        omega2
        omega3
        alpha2
        alpha3
        offset
    end
    
    methods (Access = private)
        
        function [A,B,C,D,t,th1,th2,th3] = calcPos(app)
            syms theta1 theta2 theta3 r0 r1 r2 r3
            r1 = app.R1;
            r2 = app.R2;
            r3 = app.R3;
            r0 = app.R0;
            th2=[];
            th3=[];
            w = - app.R3EditField_2.Value;
            app.W = -w;
            %Limiting angles for theta 1
            
            theta1max = acos((r0^2 + r1^2 -  ((r3 + r2)^2))/(2*r1*r0));
            tmd = rad2deg(theta1max);
            tmdi = -tmd;
            time = -(tmd - tmdi)/w;
            t=0:0.1:time;

            th1=( w*t + tmd);
 
            %Create the initial point x0.  
            
            x0 = [360 - (180 - (tmd/2));180 - (tmd/2)];
            options = optimset('Display','off');
            
            for i=1:length(th1)
            
                theta1 = th1(i);
                F = @(x) [+r1*cosd(theta1) + r2*cosd(x(1)) - r3*cosd(x(2)) - r0;
                         +r1*sind(theta1) + r2*sind(x(1)) - r3*sind(x(2)) - app.offset];
           
                %Solve the equations.
            
                [x] = fsolve(F,x0,options);
                th2 = [th2 x(1)] ;
                th3 = [th3 x(2)] ;
                x0 = [double(x(1));double(x(2))];
            end
            
            % Coordinates A B C D 
            B=[];
            C=[];
            
            A=[0,0].*ones(size(th1))';
            D=[r0,-app.offset].*ones(size(th1))';
            
            for i=1:length(th1)
            B=[ B ;[r1*cosd(th1(i)),r1*sind(th1(i))] ] ; 
            
            C=[ C ;[(r0 +  r3*cosd(th3(i))),r3*sind(th3(i))]] ;
            
            end
            
            end
        
        function a = isValid(app)
            if(app.R1 + app.R2 + app.R3 < app.R0)
                a = false ;
            elseif(app.R0 + app.R2 + app.R3 < app.R1)
                a = false;
            elseif(app.R1 + app.R0 + app.R3 < app.R2)
                a = false;
            elseif(app.R1 + app.R2 + app.R0 < app.R3)
                a = false;
            elseif(app.R1 ~= app.R3)
                a = false;
            else
                a = true;
            end
        end
        
        function [w2,w3] = calcVel(app)
            
            for i = 1: length(app.theta_1)
                A1 = [ app.R2*cosd(app.theta_2(i)) ,- app.R3*cosd(app.theta_3(i));...
                      -app.R2*sind(app.theta_2(i)) ,+ app.R3*sind(app.theta_3(i)) ];
                
                B1 = [-app.R1*app.W*cosd(app.theta_1(i));...
                     app.R1*app.W*sind(app.theta_1(i))];
                
                x = A1\B1;
                
                w2(i) = x(1,1);
                w3(i) = x(2,1);
            end
            w2 = double(w2);
            w3 = double(w3);
            
        end
        %Problem here
        function [alpha2,alpha3] = calcAcc(app)
            
            
            for i = 1: length(app.theta_1)
                A1 = [ app.R2*cosd(app.theta_2(i)) ,- app.R3*cosd(app.theta_3(i));...
                       -app.R2*sind(app.theta_2(i)) ,+ app.R3*sind(app.theta_3(i)) ];
    
                B1 = [app.R1*app.W^2*sind(app.theta_1(i)) + app.R2*app.omega2(i)^2*sind(app.theta_2(i)) - app.R3*app.omega3(i)^2*sind(app.theta_3(i));...
                     app.R1*app.W^2*cosd(app.theta_1(i)) + app.R2*app.omega2(i)^2*cosd(app.theta_2(i)) - app.R3*app.omega3(i)^2*cosd(app.theta_3(i))];
                x = A1\B1;
                alpha2(i) = x(1,1);
                alpha3(i) = x(2,1);
            end
                alpha2 = double(alpha2);
                alpha3 = double(alpha3);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TextArea.Value = "Welcome to watt linkage simulator. Note: Always press preview before new analysis";
            
            x1 =[0 2.181];
            y1 =[0 3.935];
            x2 = [4.9090 8.8];
            y2 = [2.2606 0];
            x3 = [2.181 4.9090];
            y3 = [3.935 2.2606];
            
            plot(app.UIAxes,0,0,"Color",'k',"LineWidth",2,"Marker","^","MarkerFaceColor","k")
            hold(app.UIAxes,"on")
            plot(app.UIAxes,8.8,0,"Color",'k',"LineWidth",2,"Marker","^","MarkerFaceColor","k")
            plot(app.UIAxes,x1,y1,"LineWidth",2);
            
            plot(app.UIAxes,x2,y2,"LineWidth",2);
            plot(app.UIAxes,x3,y3,"LineWidth",2);
            
            text(app.UIAxes,4,0.25,"R0")
            text(app.UIAxes,0.4,2,"R1")
            text(app.UIAxes,4.1,3,"R2")
            text(app.UIAxes,7.5,1.5,"R3")
        end

        % Callback function
        function UIAxesButtonDown(app, event)

        end

        % Button pushed function: AnimateButton
        function AnimateButtonPushed(app, event)
            cla(app.UIAxes)
            app.TextArea.Value = ".....";
            app.R0 = app.R0EditField.Value;
            app.R1 = app.R1EditField.Value;
            app.R2 = app.R2EditField.Value;
            app.R3 = app.R3EditField.Value;
            
            
            i=1;
            axis(app.UIAxes,[-1 app.R0+1 -app.R1-2 app.R1+2])
           
            
            while(i <= length(app.A))
                cla(app.UIAxes)
                
                app.TimeEditField.Value = app.time_(i);
                
                app.VelocityEditField_2.Value = - app.R3EditField_2.Value;
                app.VelocityEditField_3.Value = app.omega2(i);
                app.VelocityEditField_4.Value = app.omega3(i);
               
                app.AccelerationEditField_2.Value = 0;
                app.AccelerationEditField_3.Value = app.alpha2(i);
                app.AccelerationEditField_4.Value = app.alpha3(i);
                
                x1 =[app.A(i,1) app.B(i,1)];
                y1 =[app.A(i,2) app.B(i,2)];
                x2 = [app.C(i,1) app.D(i,1)];
                y2 = [app.C(i,2) app.D(i,2)];
                x3 = [app.B(i,1) app.C(i,1)];
                y3 = [app.B(i,2) app.C(i,2)];


                plot(app.UIAxes,0,0,"Color",'k',"LineWidth",2,"Marker","^","MarkerFaceColor","k")
                
                hold(app.UIAxes,"on")
                
                plot(app.UIAxes,app.R0,-app.offset,"Color",'k',"LineWidth",2,"Marker","^","MarkerFaceColor","k")
                plot(app.UIAxes,x1,y1,"LineWidth",2);
                
                plot(app.UIAxes,x2,y2,"LineWidth",2);
                plot(app.UIAxes,x3,y3,"LineWidth",2);


                drawnow
                
                pause(0.1)
                i = i + 1;
            end
            app.TextArea.Value = "Done";
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            cla(app.UIAxes)
      
            app.R0 = app.R0EditField.Value;
            app.R1 = app.R1EditField.Value;
            app.R2 = app.R2EditField.Value;
            app.R3 = app.R3EditField.Value;
            
            app.offset = app.OffsetSlider.Value;
            
            a = app.isValid();
            if a
            axis(app.UIAxes,[-1 app.R0+1 -app.R1-2 app.R1+2])
            
           [app.A,app.B,app.C,app.D,app.time_,app.theta_1,app.theta_2,app.theta_3] = app.calcPos();
           [app.omega2,app.omega3] = app.calcVel();
           [app.alpha2,app.alpha3] = app.calcAcc();

            
            app.TimeEditField.Value = 0;
            app.VelocityEditField_2.Value = 0;
            app.VelocityEditField_3.Value = 0;
            app.VelocityEditField_4.Value = 0;
            
            app.AccelerationEditField_2.Value = 0;
            app.AccelerationEditField_3.Value = 0;
            app.AccelerationEditField_4.Value = 0;
           
            i = 1;

            x1 =[app.A(i,1) app.B(i,1)];
            y1 =[app.A(i,2) app.B(i,2)];
            x2 = [app.C(i,1) app.D(i,1)];
            y2 = [app.C(i,2) app.D(i,2)];
            x3 = [app.B(i,1) app.C(i,1)];
            y3 = [app.B(i,2) app.C(i,2)];
            
            hold(app.UIAxes,"on")
            plot(app.UIAxes,0,0,"Color",'k',"LineWidth",2,"Marker","^","MarkerFaceColor","k")
            
            plot(app.UIAxes,app.R0,-app.offset,"Color",'k',"LineWidth",2,"Marker","^","MarkerFaceColor","k")
            plot(app.UIAxes,x1,y1,"LineWidth",2);
            
            plot(app.UIAxes,x2,y2,"LineWidth",2);
            plot(app.UIAxes,x3,y3,"LineWidth",2);
            app.TextArea.Value = "Ready for Analysis";
            else
             app.TextArea.Value = "Not Valid";   
            end
            
        end

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)
            cla(app.UIAxes)
            app.R0 = app.R0EditField.Value;
            app.R1 = app.R1EditField.Value;
            app.R2 = app.R2EditField.Value;
            app.R3 = app.R3EditField.Value;
            
            axis(app.UIAxes,[-1 app.R0+1 -app.R1-2 app.R1+2])

            index = find(round(app.time_,2) == app.TimeEditField.Value);
            
            i = index;
            
            x1 =[app.A(i,1) app.B(i,1)];
            y1 =[app.A(i,2) app.B(i,2)];
            x2 = [app.C(i,1) app.D(i,1)];
            y2 = [app.C(i,2) app.D(i,2)];
            x3 = [app.B(i,1) app.C(i,1)];
            y3 = [app.B(i,2) app.C(i,2)];
            
            app.VelocityEditField_2.Value = - app.R3EditField_2.Value;
            app.VelocityEditField_3.Value = app.omega2(i);
            app.VelocityEditField_4.Value = app.omega3(i);
            
            app.AccelerationEditField_2.Value = 0;
            app.AccelerationEditField_3.Value = app.alpha2(i);
            app.AccelerationEditField_4.Value = app.alpha3(i);
                
            plot(app.UIAxes,0,0,"Color",'k',"LineWidth",2,"Marker","^","MarkerFaceColor","k")
            hold(app.UIAxes,"on")
            plot(app.UIAxes,app.R0,-app.offset,"Color",'k',"LineWidth",2,"Marker","^","MarkerFaceColor","k")
            plot(app.UIAxes,x1,y1,"LineWidth",2);
            
            plot(app.UIAxes,x2,y2,"LineWidth",2);
            plot(app.UIAxes,x3,y3,"LineWidth",2);
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            pause(inf)
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app.UIFigure);
        end

        % Button pushed function: ViewButton
        function ViewButtonPushed(app, event)
            cla(app.UIAxes)
            xlim auto
            ylim auto
            x = app.time_;
            x(1) = [];
            y = app.omega2;
            y(1) = [];
            plot(app.UIAxes,x,y);
        end

        % Button pushed function: ViewButton_3
        function ViewButton_3Pushed(app, event)
             cla(app.UIAxes)
            xlim auto
            ylim auto
            x = app.time_;
            x(1) = [];
            y = app.omega3;
            y(1) = [];
            plot(app.UIAxes,x,y);
        end

        % Button pushed function: ViewButton_4
        function ViewButton_4Pushed(app, event)
            cla(app.UIAxes)
            xlim auto
            ylim auto
            x = app.time_;
            x(1) = [];
            y = app.alpha2;
            y(1) = [];
            plot(app.UIAxes,x,y);
        end

        % Button pushed function: ViewButton_5
        function ViewButton_5Pushed(app, event)
            cla(app.UIAxes)
            xlim auto
            ylim auto
            x = app.time_;
            x(1) = [];
            y = app.alpha3;
            y(1) = [];
            plot(app.UIAxes,x,y);
        end

        % Button pushed function: Theta1Button
        function Theta1ButtonPushed(app, event)
            cla(app.UIAxes)
            xlim auto
            ylim auto
            
            plot(app.UIAxes,app.time_,app.theta_1);
        end

        % Button pushed function: Theta2Button
        function Theta2ButtonPushed(app, event)
            cla(app.UIAxes)
            xlim auto
            ylim auto
            
            plot(app.UIAxes,app.time_,app.theta_2);
        end

        % Button pushed function: Theta3Button
        function Theta3ButtonPushed(app, event)
            cla(app.UIAxes)
            xlim auto
            ylim auto
            
            plot(app.UIAxes,app.time_,app.theta_3);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, {''; ''; ''})
            zlabel(app.UIAxes, {''; ''})
            app.AnimateButton = uibutton(app.UIFigure, 'push');
            app.AnimateButton.ButtonPushedFcn = createCallbackFcn(app, @AnimateButtonPushed, true);
            app.AnimateButton.Text = 'Animate';
            app.WattLinkageSimulatorLabel = uilabel(app.UIFigure);
            app.WattLinkageSimulatorLabel.Text = {'Watt Linkage Simulator'; ''};
            app.PreviewButton = uibutton(app.UIFigure, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = {'Preview'; ''};
            app.R0EditFieldLabel = uilabel(app.UIFigure);
            app.R0EditFieldLabel.Text = 'R0';
            app.R0EditField = uieditfield(app.UIFigure, 'numeric');
            app.R0EditField.Value = 8.8;
            app.R2EditFieldLabel = uilabel(app.UIFigure);
            app.R2EditFieldLabel.Text = 'R2';
            app.R2EditField = uieditfield(app.UIFigure, 'numeric');
            app.R2EditField.Value = 3.2;
            app.R1EditFieldLabel = uilabel(app.UIFigure);
            app.R1EditFieldLabel.Text = 'R1';
            app.R1EditField = uieditfield(app.UIFigure, 'numeric');
            app.R1EditField.Value = 4.5;
            app.R3EditFieldLabel = uilabel(app.UIFigure);
            app.R3EditFieldLabel.Text = 'R3';
            app.R3EditField = uieditfield(app.UIFigure, 'numeric');
            app.R3EditField.Value = 4.5;
            app.OffsetSliderLabel = uilabel(app.UIFigure);
            app.OffsetSliderLabel.Text = {'Offset'; ''};
            app.OffsetSlider = uislider(app.UIFigure);
            app.OffsetSlider.Limits = [0 5];
            app.TimeEditFieldLabel = uilabel(app.UIFigure);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.VelocityEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.VelocityEditField_2.Editable = 'off';
            app.VelocityEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.VelocityEditField_3.Editable = 'off';
            app.VelocityEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.VelocityEditField_4.Editable = 'off';
            app.AccelerationEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.AccelerationEditField_2.RoundFractionalValues = 'on';
            app.AccelerationEditField_2.Editable = 'off';
            app.AccelerationEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.AccelerationEditField_3.RoundFractionalValues = 'on';
            app.AccelerationEditField_3.Editable = 'off';
            app.AccelerationEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.AccelerationEditField_4.RoundFractionalValues = 'on';
            app.AccelerationEditField_4.Editable = 'off';
            app.SearchButton = uibutton(app.UIFigure, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Text = {'Search'; ''};
            app.R3EditField_2Label = uilabel(app.UIFigure);
            app.R3EditField_2Label.Text = ' |ω|(R3)';
            app.R3EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.R3EditField_2.Value = 30;
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.AngularVelocityLabel = uilabel(app.UIFigure);
            app.AngularVelocityLabel.Text = {'Angular Velocity'; ''};
            app.AngularAccelerationLabel = uilabel(app.UIFigure);
            app.AngularAccelerationLabel.Text = 'Angular Acceleration';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.ViewButton = uibutton(app.UIFigure, 'push');
            app.ViewButton.ButtonPushedFcn = createCallbackFcn(app, @ViewButtonPushed, true);
            app.ViewButton.Text = 'View';
            app.ViewButton_3 = uibutton(app.UIFigure, 'push');
            app.ViewButton_3.ButtonPushedFcn = createCallbackFcn(app, @ViewButton_3Pushed, true);
            app.ViewButton_3.Text = 'View';
            app.ViewButton_4 = uibutton(app.UIFigure, 'push');
            app.ViewButton_4.ButtonPushedFcn = createCallbackFcn(app, @ViewButton_4Pushed, true);
            app.ViewButton_4.Text = 'View';
            app.ViewButton_5 = uibutton(app.UIFigure, 'push');
            app.ViewButton_5.ButtonPushedFcn = createCallbackFcn(app, @ViewButton_5Pushed, true);
            app.ViewButton_5.Text = 'View';
            app.Theta1Button = uibutton(app.UIFigure, 'push');
            app.Theta1Button.ButtonPushedFcn = createCallbackFcn(app, @Theta1ButtonPushed, true);
            app.Theta1Button.Text = 'Theta1';
            app.Theta2Button = uibutton(app.UIFigure, 'push');
            app.Theta2Button.ButtonPushedFcn = createCallbackFcn(app, @Theta2ButtonPushed, true);
            app.Theta2Button.Text = 'Theta2';
            app.Theta3Button = uibutton(app.UIFigure, 'push');
            app.Theta3Button.ButtonPushedFcn = createCallbackFcn(app, @Theta3ButtonPushed, true);
            app.Theta3Button.Text = 'Theta3';
            app.ViewLabel = uilabel(app.UIFigure);
            app.ViewLabel.Text = 'View';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Mech

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end